classdef TopoFrac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TopoFracUIFigure               matlab.ui.Figure
        OutputPanel                    matlab.ui.container.Panel
        saveButton                     matlab.ui.control.Button
        FilenameofoutputmatandxlsxEditField  matlab.ui.control.EditField
        FilenameofoutputmatandxlsxEditFieldLabel  matlab.ui.control.Label
        EquivalentpermeabilityPanel    matlab.ui.container.Panel
        EntertherealwidthofthetraceimageunitmEditField  matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        b0mEditField                   matlab.ui.control.NumericEditField
        DistributionformoffractureapertureDropDown  matlab.ui.control.DropDown
        Label_11                       matlab.ui.control.Label
        EditField_11                   matlab.ui.control.EditField
        EditField_10                   matlab.ui.control.EditField
        EditField_9                    matlab.ui.control.EditField
        EditField_7                    matlab.ui.control.EditField
        EditField_7Label               matlab.ui.control.Label
        Button_8                       matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        EditField_6                    matlab.ui.control.EditField
        Label_9                        matlab.ui.control.Label
        CalculatethehydraulicconnectivityfButton  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        VisualizationPanel             matlab.ui.container.Panel
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_10                      matlab.ui.control.UIAxes
        UIAxes_9                       matlab.ui.control.UIAxes
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        PropertiesAnalysisPanel        matlab.ui.container.Panel
        PearsonrEditField_2            matlab.ui.control.NumericEditField
        EfficiencyEEditField_2         matlab.ui.control.NumericEditField
        SquareClusteringCsEditField    matlab.ui.control.NumericEditField
        SquareClusteringCsEditFieldLabel  matlab.ui.control.Label
        CheckBox_8                     matlab.ui.control.CheckBox
        UITable2                       matlab.ui.control.Table
        PearsonrEditField              matlab.ui.control.NumericEditField
        PearsonrEditFieldLabel         matlab.ui.control.Label
        CheckBox_7                     matlab.ui.control.CheckBox
        Label_8                        matlab.ui.control.Label
        ModularityQEditField           matlab.ui.control.NumericEditField
        ModularityQEditFieldLabel      matlab.ui.control.Label
        CheckBox_6                     matlab.ui.control.CheckBox
        SmallworldEditField_2          matlab.ui.control.NumericEditField
        SmallworldEditField_2Label     matlab.ui.control.Label
        CheckBox_5                     matlab.ui.control.CheckBox
        SmallworldEditField            matlab.ui.control.NumericEditField
        SmallworldEditFieldLabel       matlab.ui.control.Label
        CheckBox_4                     matlab.ui.control.CheckBox
        EfficiencyEEditField           matlab.ui.control.NumericEditField
        EfficiencyEEditFieldLabel      matlab.ui.control.Label
        CheckBox_3                     matlab.ui.control.CheckBox
        ClusteringCEditField_2         matlab.ui.control.NumericEditField
        ClusteringCEditField           matlab.ui.control.NumericEditField
        ClusteringCEditFieldLabel      matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        DiameterDEditField             matlab.ui.control.NumericEditField
        MNN1Label                      matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        ChoosetocalculateotherpropsofGButton  matlab.ui.control.Button
        CalculatetheedgetypeinGButton  matlab.ui.control.Button
        CalculatethedegreedistributionofGButton  matlab.ui.control.Button
        UITable_edgetype               matlab.ui.control.Table
        UITable_nodetype               matlab.ui.control.Table
        SquareClusteringCsEditField_2  matlab.ui.control.NumericEditField
        CharacterizationPanel          matlab.ui.container.Panel
        Image4                         matlab.ui.control.Image
        UITable                        matlab.ui.control.Table
        NNumberofnodesEditField        matlab.ui.control.NumericEditField
        NNumberofnodesEditFieldLabel   matlab.ui.control.Label
        NccNumberofconnectedcomponentsEditField  matlab.ui.control.NumericEditField
        NccNumberofconnectedcomponentsEditFieldLabel  matlab.ui.control.Label
        MNumberofedgesEditField        matlab.ui.control.NumericEditField
        MNumberofedgesEditFieldLabel   matlab.ui.control.Label
        ConstructGraphGButton          matlab.ui.control.Button
        InputPanel                     matlab.ui.container.Panel
        SelectButton                   matlab.ui.control.Button
        FilenameoffracturetraceimageEditField  matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
    end


    properties (Access = public)
        filename % 迹线图文件名
        prefixname % 导出Excel表格文件名

        originalimage
        grayimage
        BW
        BWicp %边的二值图
        BWsk
        BWNODE %节点的二值图
        BWcrop
        
        L_node 
        num_node
        rgb_node_label

        L_edge
        num_edge
        rgb_edge_label
        
        node_pos
        
        G
        Glargc
        A
        Alargc
        M

        n_concom
        tab_cc

        matname
        
        node_type_proportion
        edge_type_proportion
        
        wbc
        norm_wbc
        norm_wbc_mean
        closecen
        closecen_mean
        
        Cluster
        Cluster_mean
        Efficiency
        Efficiency_mean

        Pearson_coe
        Pearson_coe_Glargc
        SW_sigma
        SW_omega
        Q_modularity
        C_square
        C_square_largc
        
        Clusterlargc
        Clusterlargc_mean
        Efficiencylargc_mean
        
        f_hydraulic_connectivity
        kf_eff
        map_L
        map_H
        area

        pyG
        pyGlargc

        lay
        hcen

        Diameter
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilenameoffracturetraceimageEditField
        function FilenameoffracturetraceimageEditFieldValueChanged(app, event)
            value = app.FilenameoffracturetraceimageEditField.Value;
            app.filename = value;
            display(app.filename)
            % app.prefixname = app.SpecifytheprefixforthegeneratedfileEditField.Value;
            if isfile(app.filename)
                cla(app.UIAxes_2)
                imshow(imread(app.filename),'Parent',app.UIAxes_2)
                app.UIAxes_2.Title.String='Input Image';
            else
                uialert(app.TopoFracUIFigure,"File not found.","Invalid File");
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.tiff;*.bmp'},'Select an Image File');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                app.UIAxes_2.FontName='Times New Roman';
                disp(['User selected ', fullfile(path,file)]);
                %
                app.FilenameoffracturetraceimageEditField.Value = fullfile(path,file);
                figure(app.TopoFracUIFigure)
                [~,name,ext] = fileparts(app.FilenameoffracturetraceimageEditField.Value);
                % app.filename = [name,ext];
                app.filename = app.FilenameoffracturetraceimageEditField.Value;
                if ~isempty(app.filename)
                    display(app.filename)
                    cla(app.UIAxes_2)
                    imshow(imread(app.filename),'Parent',app.UIAxes_2)
                    app.UIAxes_2.Title.String='Input Image';
                end
            end
        end

        % Value changed function: FilenameofoutputmatandxlsxEditField
        function FilenameofoutputmatandxlsxEditFieldValueChanged(app, event)
            value = app.FilenameofoutputmatandxlsxEditField.Value;
            app.prefixname = value;
            app.matname = [app.prefixname, '.mat'];
        end

        % Button pushed function: ConstructGraphGButton
        function ConstructGraphGButtonPushed(app, event)
            %%
            nstep=9;
            d=uiprogressdlg(app.TopoFracUIFigure,'Title','Graph characterization',...
                'Message','Processing Image','Cancelable','on');
            %%
            info = imfinfo(app.filename);
            imct = info.ColorType;
            %%
            app.originalimage = imread(app.filename); % 读取图像
            % figure;subplot(1,3,1);imshow(app.originalimage);title('Original')
            % 将图像的rgb色彩空间转化为灰度图
            app.grayimage = rgb2gray(app.originalimage);
            if strcmp(imct,'truecolor')
                app.grayimage = rgb2gray(app.originalimage);
                app.BW = imbinarize(app.grayimage,'global');
            else
                if strcmp(imct,'grayscale')
                    app.grayimage = image;
                    app.BW = imbinarize(app.grayimage,'global');
                else
                    if strcmp(imct,'indexed')
                        app.grayimage = ind2gray(app.originalimage);
                        app.BW = imbinarize(app.grayimage,'global');
                    else
                        if strcmp(imct,'binary')
                            app.BW = app.originalimage;
                        end
                    end
                end
            end
            %转化为二值图像
            % app.BW = imbinarize(app.grayimage,'global');
            % subplot(1,3,2);imshow(app.BW);title('Binary')
            %求二值图像的补
            app.BWicp=imcomplement(app.BW);
            app.BWicp=bwmorph(app.BWicp,'majority');
            % subplot(1,3,3);imshow(app.BWicp);title('Binary imcomplement')

            d.Value=1/nstep;
            %%
            % 确定I型节点数量nni和位置:[nni,BWNODEI,L_node_I,image_node_I]
            d.Message='Finding I-shaped nodes';
            nskel=1;
            nnI=NODEI(app.BWicp,nskel);
            delt=Inf;
            while delt>0 || length(nnI)<3
                nskel=nskel+1;
                nni=NODEI(app.BWicp,nskel);
                nnI=[nnI,nni];
                delt=nnI(end-1)-nnI(end);
            end
            nskel=nskel-1;
            [nni,BWNODEI,app.BWsk]=NODEI(app.BWicp,nskel);
            disp(['Found',num2str(nni),' I-shaped nodes'])

            BWNODEI = BWNODEI>0;

            d.Value=2/nstep;
            %%
            % 确定X&Y型节点数量num_node_XY和位置
            d.Message='Finding Y-shaped and X-shaped nodes';
            [nnxy, BWNODEXY]=NODEXY_toplimit(app.BWsk);

            BWNODEXY = BWNODEXY>0;
            BWNODEXYI = BWNODEI | BWNODEXY;

            d.Value=3/nstep;
            %%
            app.BWcrop=(app.BWsk-BWNODEXYI)>0;
            % BWcropclean=bwmorph(app.BWcrop,'clean');
            % pnodebw=app.BWcrop-BWcropclean;
            % BWNODEXYI=BWNODEXYI+pnodebw;
            % app.BWcrop=BWcropclean;
            %%
            d.Message='Finding V-shaped nodes';

            % BWcrop0=app.BWcrop;
            % BWcrop=BWcrop0;
            bw=app.BWcrop;
            app.BWNODE=BWNODEXYI;
            nnv=Inf;%Assuming a large and impossible value
            nnV=0;% nnV是每次确定的V型节点的sum值
            BWNODEV=false(size(app.BWcrop));
            while nnv>0
                % figure,imshow(bw)
                clear nnv bwnv bwcrop bwev
                [nnv,bwcrop,bwnv,~] = NODEV(bw);
                nnV=nnV+nnv;
                BWNODEV=BWNODEV+bwnv;
                app.BWNODE=app.BWNODE+bwnv;
                clear bw
                bw=bwcrop;
            end
            disp(['Found',num2str(nnV),' V-shaped nodes'])
            clear BWcrop; 
            app.BWcrop = bw;

            d.Value=4/nstep;
            %%
            d.Message='Constructing graph';

            [app.A, edgenid, app.BWNODE, app.BWcrop] = construct_adjacency(app.BWNODE,app.BWcrop);
            app.G = adjacency2graph(app.A,edgenid,app.BWNODE,app.BWcrop);
            
            [app.G,app.A,app.BWNODE,app.BWcrop]=fixgraph(app.G,app.BWNODE,app.BWcrop,app.BWsk);

            % app.BWcrop=app.BWcrop>0;
            % [app.L_edge,app.num_edge] = bwlabel(app.BWcrop);
            % % app.rgb_edge_label = label2rgb(app.L_edge);
            % 
            % app.BWNODE=app.BWNODE>0;
            % [app.L_node,app.num_node] = bwlabel(app.BWNODE);
            % app.rgb_node_label = label2rgb(app.L_node);

            app.NNumberofnodesEditField.Value=numnodes(app.G);
            app.MNumberofedgesEditField.Value=numedges(app.G);

            cc=conncomp(app.G);
            app.n_concom=max(cc);
            app.NccNumberofconnectedcomponentsEditField.Value=max(cc);

            app.tab_cc=tabulate(cc);
            app.tab_cc=sortrows(app.tab_cc,2,'descend');
            largcnodes=find(cc==app.tab_cc(1));
            
            app.Glargc=subgraph(app.G,largcnodes);
            app.Alargc=adjacency(app.Glargc);
            
            display(app.tab_cc)
            tab_cc_t = array2table(app.tab_cc);
            display(tab_cc_t)

            tab_cc_t.(tab_cc_t.Properties.VariableNames{3}) = num2str(tab_cc_t.(tab_cc_t.Properties.VariableNames{3}));

            tab_cc_t.(tab_cc_t.Properties.VariableNames{3}) = [tab_cc_t.(tab_cc_t.Properties.VariableNames{3}),repmat('%',size(tab_cc_t,1),1)];

            app.UITable.Data = tab_cc_t;
            app.node_pos=app.G.Nodes.Position;

            deg=degree(app.G);
            indI=find(deg==1);
            indV=find(deg==2);
            indY=find(deg==3);
            indX=find(deg>=4);

            % size(app.BWNODE)
            % size(app.BWsk)
            % bwsuppos=(app.BWNODE|app.BWsk);

            app.UIAxes_3.FontName='Times New Roman';
            
            [LNODE,~]=bwlabel(app.BWNODE,4);
            s = regionprops(LNODE,'centroid');
            centroids = cat(1,s.Centroid);
            hold(app.UIAxes_3,'on')
            
            L=bwlabel(app.BWicp,4);
            RGB = label2rgb(L,'hsv','w','shuffle');

            imshow(RGB,'Parent',app.UIAxes_3);
            hold(app.UIAxes_3,'on')

            imageSize = sqrt(size(app.BWNODE,1) * size(app.BWNODE,2));
            ms = max(2, min(8, imageSize/200));

            plot(centroids(indI,1),centroids(indI,2),'b*','Parent',app.UIAxes_3,'MarkerSize',ms)
            plot(centroids(indV,1),centroids(indV,2),'c^','Parent',app.UIAxes_3,'MarkerSize',ms)
            plot(centroids(indY,1),centroids(indY,2),'mo','Parent',app.UIAxes_3,'MarkerSize',ms)
            plot(centroids(indX,1),centroids(indX,2),'g+','Parent',app.UIAxes_3,'MarkerSize',ms)
            pd=~cellfun(@isempty,{indI,indV,indY,indX});
            nm={'I','V','Y','X'};
            legend(app.UIAxes_3,nm{pd},'Location','northeastoutside','Box','on')
            %%
            d.Message='Displaying';
            
            Length=zeros(numedges(app.G),1);
            Slope=zeros(numedges(app.G),1);
            for i=1:numedges(app.G)
                n1=app.G.Edges.EndNodes(i,1);
                n2=app.G.Edges.EndNodes(i,2);
                x1=app.node_pos(n1,1);
                x2=app.node_pos(n2,1);
                y1=app.node_pos(n1,2);
                y2=app.node_pos(n2,2);
                v=[x1-x2,y1-y2];
                Length(i)=norm(v);
                Slope(i)=-(y1-y2)/(x1-x2);
            end
            Slope=atand(Slope);
            Slope(Slope<0)=Slope(Slope<0)+180;
            app.G.Edges.Length = Length;
            app.G.Edges.Slope = Slope;


            d.Value=5/nstep;
            %%
            d.Message='Displaying';            
            
            app.UIAxes.FontName='Times New Roman';
            cla(app.UIAxes)
            clmap=colormap(app.UIAxes,"abyss");
            LWidths = 2*app.G.Edges.Length/max(app.G.Edges.Length);
            spcl = 1+round(255*(Slope-min(Slope))./(max(Slope)-min(Slope)));
            disp(spcl)
            for i=1:numedges(app.G)
                n1=app.G.Edges.EndNodes(i,1);
                n2=app.G.Edges.EndNodes(i,2);
                x1=app.node_pos(n1,1);
                x2=app.node_pos(n2,1);
                y1=app.node_pos(n1,2);
                y2=app.node_pos(n2,2);
                x=[x1,x2];
                y=[y1,y2];
                line(x,y,'LineStyle','-','Color',clmap(spcl(i),:),'LineWidth',LWidths(i,1),'Parent',app.UIAxes)
            end
            % colormap(app.UIAxes,"colorcube")
            cb=colorbar(app.UIAxes,"eastoutside",'Ticks',linspace(0,1,10),...
                'TickLabels',{'0','20','40','60','80','100','120','140','160','180'});
            cb.Label.String='Slope';
            [image_row,image_col] = size(app.BWNODE);
            app.UIAxes.XLim=[0,image_col];
            app.UIAxes.YLim=[0,image_row];
            app.UIAxes.YDir="reverse";
            % app.UIAxes.Box="on";
            % app.UIAxes.XAxis.Visible="on";
            % app.UIAxes.YAxis.Visible="on";
            % app.UIAxes.Title.String = 'Graph';
            daspect(app.UIAxes,[1 1 1])
            % axis(app.UIAxes,'tight')
            app.UIAxes.XColor = [0.68 0.68 0.68];
            app.UIAxes.YColor = [0.68 0.68 0.68];

            d.Value=6/nstep;
            
            %%
            d.Message='Displaying';
            
            n = numnodes(app.G);
            
            app.G.Edges.Weight = ones(numedges(app.G),1);

            app.wbc = centrality(app.G,'betweenness','Cost',app.G.Edges.Weight);
            app.norm_wbc = 2*app.wbc./((n-2)*(n-1));
            app.G.Nodes.Norm_wbc=app.norm_wbc;
            app.norm_wbc_mean = mean(app.norm_wbc);
            disp('介数中心性：')
            disp(app.norm_wbc);
            
            app.closecen = centrality(app.G,'closeness','Cost',app.G.Edges.Weight);
            app.G.Nodes.Closecen=app.closecen;
            app.closecen_mean = mean(app.closecen);

            disp('接近中心性：')
            disp(app.closecen)
            % deg = centrality(H,'degree','importance',H.Edges.Weight);
            % nColors = 8e-0*(norm_wbc-min(norm_wbc)+0.2).^(1/1);
            nColors = app.norm_wbc;
            % nSizes = normalize(app.closecen,'scale');
            nSizes = rescale(app.closecen,1,5);
            LWidths = 1*app.G.Edges.Weight/max(app.G.Edges.Weight);
            
            
            app.UIAxes_5.FontName='Times New Roman';
            cla(app.UIAxes_5)
            colormap(app.UIAxes_5,"jet")
            % colormap(flip(parula,1));
            app.hcen=plot(app.G,'MarkerSize',nSizes,'NodeCData',nColors,...
                'EdgeAlpha',1,...
                'NodeFontName','Calibri',...
                'NodeLabel',{},...
                'NodeFontSize',12,...
                'LineWidth',LWidths,...
                'EdgeColor',[0.74 0.74 0.74],...
                'XData',app.node_pos(:,1),'YData',app.node_pos(:,2),...
                'Parent',app.UIAxes_5);
            % 'auto',...
            % 'Layout',...
            app.LayoutDropDown.Value = 'Original position';
            % layout(app.hcen,'force','UseGravity',true)
            app.UIAxes_5.YDir="reverse";
            cb_4 = colorbar(app.UIAxes_5,'FontSize',8,'FontName','Times New Roman');
            cb_4.Label.String = 'Betweenness Centrality';
            % cb.Ticks=linspace(0,1,10);
            % cb.TickLabels={'0','20','40','60','80','100','120','140','160','180'};
            axis(app.UIAxes_5,'equal')
            app.UIAxes_5.Title.String = 'Graph in different Layout';
            % set(app.UIAxes_4,'FontSize',12,'FontName','Helvetica')
            
            app.UIAxes_5.XColor = [0.68 0.68 0.68];
            app.UIAxes_5.YColor = [0.68 0.68 0.68];
            app.LayoutDropDown.Visible="on";

            d.Value=7/nstep;

          
            %%
            

            y1=app.norm_wbc;
            % rg1=max(y1)-min(y1);
            % h1 = histogram(y1,'Parent',app.UIAxes_6);
            % h1.Normalization = 'probability';
            % h1.BinWidth=rg1/15;
            % h1.FaceColor=[];
            % h1.Color
            % app.UIAxes_6.XTickLabelMode = 'auto'; 
            % app.UIAxes_6.YTickLabelMode = 'auto'; 
            % app.UIAxes_6.Title.String = 'Normalized betweenness centrality';
            % app.UIAxes_6.XColor = 'k';
            % app.UIAxes_6.YColor = 'k';

            d.Value=8/nstep;
            %%
            y2=app.closecen;
            % rg2=max(y2)-min(y2);
            % h2 = histogram(y2,'Parent',app.UIAxes_7);
            % h2.Normalization = 'probability';
            % h2.BinWidth=rg2/15;
            % h2.FaceColor=rand(1,3);
            % app.UIAxes_7.XTickLabelMode = 'auto'; 
            % app.UIAxes_7.YTickLabelMode = 'auto'; 
            % app.UIAxes_7.Title.String = 'Closeness centrality';
            % app.UIAxes_7.XColor = 'k';
            % app.UIAxes_7.YColor = 'k';
            d.Value=9/nstep;

            %%
            % 将数据放进元胞数组
            PntSet={[y1,y2]};
            axM = app.UIAxes_7;
            % figure图床及主要区域axes坐标区域创建及基础修饰
            % fig=figure('Units','normalized','Position',[.3,.2,.5,.63]);
            % axM=axes('Parent',fig);hold on;
            set(axM,'LineWidth',1.1,'Box','on','TickDir','in','XColor','k','YColor','k',...
                'XMinorTick','on','YMinorTick','on','XGrid','on','YGrid','on','GridLineStyle','--',...
                'FontName','Times New Roman','FontSize',12,'GridAlpha',.09,'XTickLabelMode','auto','YTickLabelMode','auto')
            axM.XLabel.String='Normalized betweenness centrality';
            axM.YLabel.String='Closeness centrality';
            axM.XLabel.FontSize=12;
            axM.YLabel.FontSize=12;

            % -------------------------------------------------------------------------
            % 右侧axes坐标区域创建及基础修饰
            axR = app.UIAxes_6;
            set(axR,'LineWidth',1.1,'Box','off','TickDir','out','XColor','k','YColor','k',...
                'XMinorTick','on','YMinorTick','on','XGrid','on','YGrid','on','GridLineStyle','--',...
                'FontName','Times New Roman','FontSize',12,'GridAlpha',.09 ,'TickLength',[.006 .015],...%'YTickLabel','',...
                'XTickLabelMode','auto','YTickLabelMode','auto') %
            axR.XLabel.String='Y-axis data statistics';%'\it{C_c}';
            axR.XLabel.FontSize=12;

            % -------------------------------------------------------------------------
            % 上方axes坐标区域创建及基础修饰
            axU = app.UIAxes_10;
            set(axU,'LineWidth',1.1,'Box','off','TickDir','out','XColor','k','YColor','k',...
                'XMinorTick','on','YMinorTick','on','XGrid','on','YGrid','on','GridLineStyle','--',...
                'FontName','Times New Roman','FontSize',12,'GridAlpha',.09,'TickLength',[.006 .015],...%'XTickLabel','',...
                'XTickLabelMode','auto','YTickLabelMode','auto') %
            axU.YLabel.String='X-axis data statistics'; % '\it{C_b}';
            axU.YLabel.FontSize=12;

            % -------------------------------------------------------------------------
            % 配色预设(here to change the default color list)
            C1=[211 43 43;61 96 137;249 206 61;76 103 86;80 80 80]./255;
            C2=[102,173,194;36,59,66;232,69,69;194,148,102;54,43,33]./255;
            C3=[223,122,94;60 64 91;130 178 154;244,241,222;240 201 134]./255;
            C4=[122,117,119;255,163,25;135,146,73;126,15,4;30,93,134]./255;
            C5=[198,199,201;38,74,96;209,80,51;241,174,44;12,13,15]./255;
            C6=[235,75,55;77,186,216;58,84,141;2,162,136;245,155,122]./255;
            C7=[23,23,23;121,17,36;31,80,91;44,9,75;61,36,42]./255;
            C8=[47,62,66;203,129,70;0 64 115;152,58,58;20 72 83]./255;
            colorList=C2;
            
            % 在这改边缘图及中心图种类
            % (here to change the type of marginal plot and main plot)
            %    |
            %    |
            %    |
            % \  |  /
            %  \ | /
            %   \|/
            %
            mainType=1;
            marginType=1;
            %
            %   /|\
            %  / | \
            % /  |  \
            %    |
            %    |
            %    |
            
            % 主要区域散点图绘制
            switch mainType
                case 1 % 散点图
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        scatter(axM,tPntSet(:,1),tPntSet(:,2),35,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.6)
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,legendStr,'FontSize',14,'Box','off','Location','best');
                case 2 % 等高线图
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        gridx1=linspace(min(tPntSet(:,1)),max(tPntSet(:,1)),round(sqrt(size(tPntSet,1))));
                        gridx2=linspace(min(tPntSet(:,2)),max(tPntSet(:,2)),round(sqrt(size(tPntSet,1))));
                        [x1,x2]=meshgrid(gridx1, gridx2);
                        [m,n]=size(x1);
                        x1=x1(:);
                        x2=x2(:);
                        xi=[x1,x2];
                        h=ksdensity(tPntSet,xi);
                        contour(axM,reshape(x1,[m,n]),reshape(x2,[m,n]),reshape(h,[m,n]),...
                            'LineWidth',1.2,'EdgeColor',colorList(i,:))
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,legendStr,'FontSize',14,'Box','off','Location','best');
                case 3 % 回归曲线
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        [p,S]=polyfit(tPntSet(:,1),tPntSet(:,2),1);
                        x1=linspace(min(tPntSet(:,1)),max(tPntSet(:,1)),100);
                        [y_fit,delta]=polyval(p,x1,S);
                        % 绘制原始数据、线性拟合和 95% 预测区间 y±2Δ。
                        uy=y_fit+2.*delta;
                        dy=y_fit-2.*delta;
                        % 绘制原始数据
                        sHdl(i)=scatter(axM,tPntSet(:,1),tPntSet(:,2),30,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.9);
                        % 绘制拟合曲线
                        plot(axM,x1,y_fit,'Color',colorList(i,:),'LineWidth',2.5)
                        % 绘制置信区间
                        fill(axM,[x1,x1(end:-1:1)],[uy,dy(end:-1:1)],colorList(i,:),'EdgeColor','none','FaceAlpha',.15)
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,sHdl,legendStr,'FontSize',14,'Box','off','Location','best');
                case 4 % 散点+凸包
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        [k,av]=convhull(tPntSet);
                        sHdl(i)=scatter(axM,tPntSet(:,1),tPntSet(:,2),30,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.9);
                        fill(axM,tPntSet(k,1),tPntSet(k,2),colorList(i,:),'FaceAlpha',0.3,'EdgeColor',colorList(i,:),'LineWidth',2)
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,sHdl,legendStr,'FontSize',14,'Box','off','Location','best');
                case 5 % 散点+不规则图形
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        sHdl(i)=scatter(axM,tPntSet(:,1),tPntSet(:,2),30,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.9);
                        R=max(max(tPntSet(:,1))-min(tPntSet(:,1)),max(tPntSet(:,2))-min(tPntSet(:,2)))./round(sqrt(size(tPntSet,1)));
                        t=linspace(0,2*pi,100);
                        [k,~]=convhull(tPntSet);
                        kPntSet=tPntSet(k,:);
                        kX=repmat(kPntSet(:,1),[1,100])+repmat(cos(t).*R,[length(k),1]);
                        kY=repmat(kPntSet(:,2),[1,100])+repmat(sin(t).*R,[length(k),1]);
                        kX=kX(:);kY=kY(:);
                        [k,av]=convhull([kX,kY]);
                        fill(axM,kX(k),kY(k),colorList(i,:),'FaceAlpha',0.3,'EdgeColor',colorList(i,:),'LineWidth',2)
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,sHdl,legendStr,'FontSize',14,'Box','off','Location','best');
                case 6 % 椭圆形
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        sHdl(i)=scatter(axM,tPntSet(:,1),tPntSet(:,2),30,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.9);
                        Mu=mean(tPntSet);
                        Y=tPntSet-repmat(Mu,size(tPntSet,1),1);
                        Sigma=cov(Y);% Sigma=(Y'*(ones(size(pntSet,1),1).*Y))./(size(pntSet,1)-1);
                        [X,Y]=getEllipse(Mu,Sigma,9.21,100);
                        fill(axM,X,Y,colorList(i,:),'FaceAlpha',0.3,'EdgeColor',colorList(i,:),'LineWidth',2);
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,sHdl,legendStr,'FontSize',14,'Box','off','Location','best');
                case 7 % 簇状
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        sHdl(i)=scatter(axM,tPntSet(:,1),tPntSet(:,2),30,'filled','CData',colorList(i,:),'MarkerFaceAlpha',.9);
                        Mu=mean(tPntSet);
                        LX=[tPntSet(:,1),Mu(1).*ones(size(tPntSet,1),1),nan(size(tPntSet,1),1)]';
                        LY=[tPntSet(:,2),Mu(2).*ones(size(tPntSet,1),1),nan(size(tPntSet,1),1)]';
                        plot(axM,LX(:),LY(:),'Color',colorList(i,:),'LineWidth',1.2);
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,sHdl,legendStr,'FontSize',14,'Box','off','Location','best');
                case 8 % 误差棒
                    for i=1:length(PntSet)
                        tPntSet=PntSet{i};
                        Mu=mean(tPntSet);
                        SX=std(tPntSet(:,1));
                        SY=std(tPntSet(:,2));
                        errorbar(axM,Mu(1),Mu(2),SY,SY,SX,SX,'o','LineWidth',1.5,'Color',colorList(i,:),'MarkerSize',10);
                    end
                    % legendStr{length(PntSet)}='';
                    % for i=1:length(PntSet)
                    %     legendStr{i}=['Class-',num2str(i)];
                    % end
                    % legend(axM,legendStr,'FontSize',14,'Box','off','Location','best');
            end
            % 边际图绘制
            axR.YLim=axM.YLim;
            axU.XLim=axM.XLim;
            for i=1:length(PntSet)
                tPntSet=PntSet{i};
                switch marginType
                    case 1 % 柱状图
                        histogram(axR,tPntSet(:,2),'FaceColor',colorList(i,:),'Orientation','horizontal',...
                            'EdgeColor',[1 1 1]*.1,'FaceAlpha',0.6,'LineWidth',.8,...
                            'Normalization','probability','BinWidth',(max(tPntSet(:,2))-min(tPntSet(:,2)))/6 );
                        histogram(axU,tPntSet(:,1),'FaceColor',colorList(i,:),...
                            'EdgeColor',[1 1 1]*.1,'FaceAlpha',0.6,'LineWidth',.8,...
                            'Normalization','probability','BinWidth',(max(tPntSet(:,1))-min(tPntSet(:,1)))/10 );
                    case 2 % 核密度面
                        [f,yi]=ksdensity(tPntSet(:,2));
                        fill(axR,[f,0],[yi,yi(1)],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none')
                        [f,xi]=ksdensity(tPntSet(:,1));
                        fill(axU,[xi,xi(1)],[f,0],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none')
                    case 3 % 核密度线
                        [f,yi]=ksdensity(tPntSet(:,2));
                        plot(axR,f,yi,'Color',colorList(i,:),'LineWidth',2)
                        [f,xi]=ksdensity(tPntSet(:,1));
                        plot(axU,xi,f,'Color',colorList(i,:),'LineWidth',2)
                    case 4 % 核密度面+线
                        [f,yi]=ksdensity(tPntSet(:,2));
                        fill(axR,[f,0],[yi,yi(1)],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none');
                        plot(axR,f,yi,'Color',colorList(i,:),'LineWidth',1.2)
                        [f,xi]=ksdensity(tPntSet(:,1));
                        fill(axU,[xi,xi(1)],[f,0],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none');
                        plot(axU,xi,f,'Color',colorList(i,:),'LineWidth',1.2)
                    case 5 % 直方图+密度线
                        HR=histogram(axR,tPntSet(:,2),'FaceColor',colorList(i,:),'Orientation','horizontal',...
                            'EdgeColor',[1 1 1]*.1,'FaceAlpha',0.6,'LineWidth',.8);
                        HU=histogram(axU,tPntSet(:,1),'FaceColor',colorList(i,:),...
                            'EdgeColor',[1 1 1]*.1,'FaceAlpha',0.6,'LineWidth',.8);
                        [f,yi]=ksdensity(tPntSet(:,2));
                        plot(axR,f.*size(tPntSet,1).*HR.BinWidth,yi,'Color',colorList(i,:),'LineWidth',2)
                        [f,xi]=ksdensity(tPntSet(:,1));
                        plot(axU,xi,f.*size(tPntSet,1).*HU.BinWidth,'Color',colorList(i,:),'LineWidth',2)
                    case 6 % 箱线图
                        tjDataY=tPntSet(:,2);fullDataY=tjDataY;
                        outliBool=isoutlier(tjDataY,'quartiles');
                        outli=tjDataY(outliBool);
                        scatter(axR,repmat(i,[sum(outliBool),1]),outli,45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataY(outliBool)=[];
                        qt25=quantile(fullDataY,0.25);
                        qt75=quantile(fullDataY,0.75);
                        med=median(fullDataY);
                        plot(axR,[i,i],[max(tjDataY),qt75],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[i,i],[min(tjDataY),qt25],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[-.15,.15]+i,[max(tjDataY),max(tjDataY)],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[-.15,.15]+i,[min(tjDataY),min(tjDataY)],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[-.25,.25]+i,[med,med],'LineWidth',1.2,'Color',colorList(i,:))
                        fill(axR,i+.25.*1.*[-1 1 1 -1],[qt25,qt25,qt75,qt75],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        axR.XLim=[0,length(PntSet)+1];
                        % -----------------------------------------------------------------
                        tjDataX=tPntSet(:,1);fullDataX=tjDataX;
                        outliBool=isoutlier(tjDataX,'quartiles');
                        outli=tjDataX(outliBool);
                        scatter(axU,outli,repmat(i,[sum(outliBool),1]),45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataX(outliBool)=[];
                        qt25=quantile(fullDataX,0.25);
                        qt75=quantile(fullDataX,0.75);
                        med=median(fullDataX);
                        plot(axU,[max(tjDataX),qt75],[i,i],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[min(tjDataX),qt25],[i,i],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[max(tjDataX),max(tjDataX)],[-.15,.15]+i,'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[min(tjDataX),min(tjDataX)],[-.15,.15]+i,'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[med,med],[-.25,.25]+i,'LineWidth',1.2,'Color',colorList(i,:))
                        fill(axU,[qt25,qt25,qt75,qt75],i+.25.*1.*[-1 1 1 -1],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        axU.YLim=[0,length(PntSet)+1];
                    case 7 % 小提琴图
                        tjDataY=tPntSet(:,2);fullDataY=tjDataY;
                        [f,yi]=ksdensity(tjDataY);
                        f(yi>max(tjDataY))=[];yi(yi>max(tjDataY))=[];
                        f(yi<min(tjDataY))=[];yi(yi<min(tjDataY))=[];
                        fill(axR,[f,-f(end:-1:1)].*1.2+i,[yi,yi(end:-1:1)],colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        outliBool=isoutlier(tjDataY,'quartiles');
                        outli=tjDataY(outliBool);
                        scatter(axR,repmat(i,[sum(outliBool),1]),outli,45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataY(outliBool)=[];
                        qt25=quantile(fullDataY,0.25);
                        qt75=quantile(fullDataY,0.75);
                        med=median(fullDataY);
                        plot(axR,[i,i],[max(tjDataY),qt75],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[i,i],[min(tjDataY),qt25],'LineWidth',1.2,'Color',[0,0,0])
                        fill(axR,i+.2.*1.*[-1 1 1 -1],[qt25,qt25,qt75,qt75],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axR,[-.2,.2]+i,[med,med],'LineWidth',1.2,'Color',colorList(i,:))
                        axR.XLim=[0,length(PntSet)+1];
                        % -----------------------------------------------------------------
                        tjDataX=tPntSet(:,1);fullDataX=tjDataX;
                        [f,xi]=ksdensity(tjDataX);
                        f(xi>max(tjDataX))=[];xi(xi>max(tjDataX))=[];
                        f(xi<min(tjDataX))=[];xi(xi<min(tjDataX))=[];
                        fill(axU,[xi,xi(end:-1:1)],[f,-f(end:-1:1)].*1.2+i,colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        outliBool=isoutlier(tjDataX,'quartiles');
                        outli=tjDataX(outliBool);
                        scatter(axU,outli,repmat(i,[sum(outliBool),1]),45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataX(outliBool)=[];
                        qt25=quantile(fullDataX,0.25);
                        qt75=quantile(fullDataX,0.75);
                        med=median(fullDataX);
                        plot(axU,[max(tjDataX),qt75],[i,i],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[min(tjDataX),qt25],[i,i],'LineWidth',1.2,'Color',[0,0,0])
                        fill(axU,[qt25,qt25,qt75,qt75],i+.25.*1.*[-1 1 1 -1],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axU,[med,med],[-.25,.25]+i,'LineWidth',1.2,'Color',colorList(i,:))
                        axU.YLim=[0,length(PntSet)+1];
                    case 8 % 线条散点
                        LY=[tPntSet(:,2),tPntSet(:,2),tPntSet(:,2).*nan]';
                        LX=repmat([i-.3,i+.3,nan],[size(tPntSet,1),1])';
                        line(axR,LX(:),LY(:),'Color',[colorList(i,:),.4],'lineWidth',1)
                        axR.XLim=[0,length(PntSet)+1];
                        LX=[tPntSet(:,1),tPntSet(:,1),tPntSet(:,1).*nan]';
                        LY=repmat([i-.3,i+.3,nan],[size(tPntSet,1),1])';
                        line(axU,LX(:),LY(:),'Color',[colorList(i,:),.4],'lineWidth',1)
                        axU.YLim=[0,length(PntSet)+1];
                    case 9 % 山脊图
                        [f,yi]=ksdensity(tPntSet(:,2));
                        fill(axR,[f,0]+(i-1).*.5,[yi,yi(1)],colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none');
                        plot(axR,f+(i-1).*.5,yi,'Color',colorList(i,:),'LineWidth',1.2)
                        [f,xi]=ksdensity(tPntSet(:,1));
                        fill(axU,[xi,xi(1)],[f,0]+(i-1).*.5,colorList(i,:),...
                            'FaceAlpha',0.3,'EdgeColor','none');
                        plot(axU,xi,f+(i-1).*.5,'Color',colorList(i,:),'LineWidth',1.2)
                    case 10 % 类小提琴图
                        tjDataY=tPntSet(:,2);fullDataY=tjDataY;
                        [f,yi]=ksdensity(tjDataY);
                        f(yi>max(tjDataY))=[];yi(yi>max(tjDataY))=[];
                        f(yi<min(tjDataY))=[];yi(yi<min(tjDataY))=[];
                        fill(axR,[0,f,0].*1.2+i+.15,[yi(1),yi,yi(end)],colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        outliBool=isoutlier(tjDataY,'quartiles');
                        outli=tjDataY(outliBool);
                        scatter(axR,repmat(i-.15,[sum(outliBool),1]),outli,45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataY(outliBool)=[];
                        qt25=quantile(fullDataY,0.25);
                        qt75=quantile(fullDataY,0.75);
                        med=median(fullDataY);
                        plot(axR,[i,i]-.2,[max(tjDataY),qt75],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[i,i]-.2,[min(tjDataY),qt25],'LineWidth',1.2,'Color',[0,0,0])
                        fill(axR,i-.2+.2.*1.*[-1 1 1 -1],[qt25,qt25,qt75,qt75],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axR,[-.2,.2]+i-.2,[med,med],'LineWidth',1.2,'Color',colorList(i,:))
                        axR.XLim=[0,length(PntSet)+1];
                        % -----------------------------------------------------------------
                        tjDataX=tPntSet(:,1);fullDataX=tjDataX;
                        [f,xi]=ksdensity(tjDataX);
                        f(xi>max(tjDataX))=[];xi(xi>max(tjDataX))=[];
                        f(xi<min(tjDataX))=[];xi(xi<min(tjDataX))=[];
                        fill(axU,[xi(1),xi,xi(end)],[0,f,0].*1.2+i+.15,colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        outliBool=isoutlier(tjDataX,'quartiles');
                        outli=tjDataX(outliBool);
                        scatter(axU,outli,repmat(i-.2,[sum(outliBool),1]),45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.8)
                        tjDataX(outliBool)=[];
                        qt25=quantile(fullDataX,0.25);
                        qt75=quantile(fullDataX,0.75);
                        med=median(fullDataX);
                        plot(axU,[max(tjDataX),qt75],[i,i]-.2,'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[min(tjDataX),qt25],[i,i]-.2,'LineWidth',1.2,'Color',[0,0,0])
                        fill(axU,[qt25,qt25,qt75,qt75],i-.2+.25.*1.*[-1 1 1 -1],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axU,[med,med],[-.25,.25]+i-.2,'LineWidth',1.2,'Color',colorList(i,:))
                        axU.YLim=[0,length(PntSet)+1];
                    case 11 % 雨云图
                        tjDataY=tPntSet(:,2);fullDataY=tjDataY;
                        [f,yi]=ksdensity(tjDataY);
                        f(yi>max(tjDataY))=[];yi(yi>max(tjDataY))=[];
                        f(yi<min(tjDataY))=[];yi(yi<min(tjDataY))=[];
                        fill(axR,[0,f,0].*1.2+i+.15,[yi(1),yi,yi(end)],colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        scatter(axR,tPntSet(:,2).*0+i-.2+.22.*(rand(size(tPntSet,1),1)-.5).*2,tPntSet(:,2),45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.3)
                        tjDataY(outliBool)=[];
                        qt25=quantile(fullDataY,0.25);
                        qt75=quantile(fullDataY,0.75);
                        med=median(fullDataY);
                        plot(axR,[i,i]-.2,[max(tjDataY),qt75],'LineWidth',1.2,'Color',[0,0,0])
                        plot(axR,[i,i]-.2,[min(tjDataY),qt25],'LineWidth',1.2,'Color',[0,0,0])
                        fill(axR,i-.2+.2.*1.*[-1 1 1 -1],[qt25,qt25,qt75,qt75],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axR,[-.2,.2]+i-.2,[med,med],'LineWidth',1.2,'Color',colorList(i,:))
                        axR.XLim=[0,length(PntSet)+1];
                        % -----------------------------------------------------------------
                        tjDataX=tPntSet(:,1);fullDataX=tjDataX;
                        [f,xi]=ksdensity(tjDataX);
                        f(xi>max(tjDataX))=[];xi(xi>max(tjDataX))=[];
                        f(xi<min(tjDataX))=[];xi(xi<min(tjDataX))=[];
                        fill(axU,[xi(1),xi,xi(end)],[0,f,0].*1.2+i+.15,colorList(i,:),'FaceAlpha',0.3,...
                            'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        scatter(axU,tPntSet(:,1),tPntSet(:,1).*0+i-.2+.22.*(rand(size(tPntSet,1),1)-.5),45,'filled',...
                            'CData',colorList(i,:),'LineWidth',1.2,'MarkerFaceAlpha',0.3)
                        tjDataX(outliBool)=[];
                        qt25=quantile(fullDataX,0.25);
                        qt75=quantile(fullDataX,0.75);
                        med=median(fullDataX);
                        plot(axU,[max(tjDataX),qt75],[i,i]-.2,'LineWidth',1.2,'Color',[0,0,0])
                        plot(axU,[min(tjDataX),qt25],[i,i]-.2,'LineWidth',1.2,'Color',[0,0,0])
                        fill(axU,[qt25,qt25,qt75,qt75],i-.2+.25.*1.*[-1 1 1 -1],[1,1,1],...
                            'FaceAlpha',0.95,'EdgeColor',colorList(i,:),'LineWidth',1.2);
                        plot(axU,[med,med],[-.25,.25]+i-.2,'LineWidth',1.2,'Color',colorList(i,:))
                        axU.YLim=[0,length(PntSet)+1];
                end
            end
            % linkaxes([axM,axR],'y')
            % linkaxes([axM,axU],'x')
            
            standardpos = axM.Position;
            axU.Position(1) = standardpos(1);
            axU.Position(3) = standardpos(3);
            axM.Position(2) = standardpos(2);
            axM.Position(4) = standardpos(4);
            %% ========================================================================
            % 所使用到的函数
            % 置信椭圆定位函数
            function [X,Y]=getEllipse(Mu,Sigma,S,pntNum)
                % 置信区间 | 95%:5.991  99%:9.21  90%:4.605
                % (X-Mu)*inv(Sigma)*(X-Mu)=S

                invSig=inv(Sigma);

                [V,D]=eig(invSig);
                aa=sqrt(S/D(1));
                bb=sqrt(S/D(4));

                t=linspace(0,2*pi,pntNum);
                XY=V*[aa*cos(t);bb*sin(t)];
                X=(XY(1,:)+Mu(1))';
                Y=(XY(2,:)+Mu(2))';
            end
            %% ========================================================================
            % plot(y1,y2,'o','Parent',app.UIAxes_6)
            
        end

        % Button pushed function: CalculatethedegreedistributionofGButton
        function CalculatethedegreedistributionofGButtonPushed(app, event)
            deg = degree(app.G);
            app.G.Nodes.Degree=deg;
            deg_tab = tabulate(deg);
            deg_tab = sortrows(deg_tab,1,"ascend");
            if size(deg_tab,1)>4
                for i=5:size(deg_tab,1)
                    deg_tab(4,2:3) = deg_tab(4,2:3)+deg_tab(i,2:3);
                end
            end
            deg_tab(5:end,:)=[];
            % max_deg=max(deg);
            % fh=histogram(deg,0.5:1:max_deg+0.5,'Normalization','probability','Parent',app.UIAxes_5);
            % set(gca,'FontName','微软雅黑')

            display(deg_tab)
            fi = zeros(4,1);
            for i = 1:4
                ise = find(deg_tab(:,1)==i);
                if ~isempty(ise)
                    fi(i) = 1;
                end
            end
            deg_tab_new = zeros(4,3);
            for i = 1:4
                if fi(i)==1
                    deg_tab_new(i,:) = deg_tab(i,:);
                else
                    deg_tab_new(i,:) = [i, 0, 0];
                end
            end
            disp(deg_tab_new)
            
            app.UIAxes_8.FontName='Times New Roman';
            app.node_type_proportion = deg_tab_new(:,3)';
            p=pie(app.node_type_proportion(app.node_type_proportion>0),'Parent',app.UIAxes_8);
            
            colormap(app.UIAxes_8,"colorcube")
            node_labels = {'I','V','Y','X'};
            % set(app.UIAxes_8,'FontName','Helvetica')
            app.UIAxes_8.Title.String = 'Degree distribution';
            % Create legend
            node_labels_final=node_labels(app.node_type_proportion>0);
            legend(app.UIAxes_8,node_labels_final,'Location','southeastoutside','FontAngle','italic',...
                'FontName','Times New Roman');

            fprintf('节点分布node_type_proportion:\n')
            display(app.node_type_proportion)

            datals = table('Size',[1,4],...
                'VariableTypes',{'string','string','string','string'},...
                'VariableNames', node_labels);

            for i=1:4
                name = node_labels{i};
                num = app.node_type_proportion(i);
                datals.(name) = [num2str(num,'%.2f'), '%'];
            end
            app.UITable_nodetype.Data = datals;

            % saveas(gcf,[prefixname,'-degree-his.fig'])
            % saveas(gcf,[prefixname,'-degree-his.png'])
            % print(gcf,[prefixname,'-degree-his.tif'],'-r300','-dtiffn')
            %             wfilename='GSI-statistics.xlsx';
            %             writematrix(deg_his',wfilename,'Sheet',app.prefixname,'Range','A1')
            %             save(app.matname)


        end

        % Button pushed function: CalculatetheedgetypeinGButton
        function CalculatetheedgetypeinGButtonPushed(app, event)
            %%
            type_II=zeros(numedges(app.G),1); % 1 1
            type_IV=zeros(numedges(app.G),1); % 1 2
            type_IY=zeros(numedges(app.G),1); % 1 3
            type_IX=zeros(numedges(app.G),1); % 1 4(>4)

            type_VV=zeros(numedges(app.G),1); % 2 2
            type_VY=zeros(numedges(app.G),1); % 2 3
            type_VX=zeros(numedges(app.G),1); % 2 4

            type_YY=zeros(numedges(app.G),1); % 3 3
            type_YX=zeros(numedges(app.G),1); % 3 4(>4)

            type_XX=zeros(numedges(app.G),1); % 4(>4) 4(>4)

            deg = degree(app.G);

            for i=1:numedges(app.G)
                n1=app.G.Edges.EndNodes(i,1);
                n2=app.G.Edges.EndNodes(i,2);
                d1=deg(n1);
                d2=deg(n2);
                d=[d1,d2];
                if ~isempty(find(d==1))
                    if sum(d)==2
                        type_II(i)=1;
                    end
                    if sum(d)==3
                        type_IV(i)=1;
                    end
                    if sum(d)==4
                        type_IY(i)=1;
                    end
                    if sum(d)>=5
                        type_IX(i)=1;
                    end
                end
                if ~isempty(find(d==2))
                    if sum(d)==3
                        type_IV(i)=1;
                    end
                    if sum(d)==4
                        type_VV(i)=1;
                    end
                    if sum(d)==5
                        type_VY(i)=1;
                    end
                    if sum(d)>=6
                        type_VX(i)=1;
                    end
                end
                if ~isempty(find(d==3))
                    if sum(d)==4
                        type_IY(i)=1;
                    end
                    if sum(d)==5
                        type_VY(i)=1;
                    end
                    if sum(d)==6
                        type_YY(i)=1;
                    end
                    if sum(d)>=7
                        type_YX(i)=1;
                    end
                end
                if ~isempty(find(d==4))
                    if sum(d)==5
                        type_IX(i)=1;
                    end
                    if sum(d)==6
                        type_VX(i)=1;
                    end
                    if sum(d)==7
                        type_YX(i)=1;
                    end
                    if sum(d)>=8
                        type_XX(i)=1;
                    end
                end
            end


            % type_II+type_IV+type_IY+type_IX+type_VV+type_VY+type_VX+type_YY+type_YX+type_XX
            edge_type=zeros(10,1);
            edge_type(1,1)=sum(type_II);
            edge_type(2,1)=sum(type_IV);
            edge_type(3,1)=sum(type_IY);
            edge_type(4,1)=sum(type_IX);
            edge_type(5,1)=sum(type_VV);
            edge_type(6,1)=sum(type_VY);
            edge_type(7,1)=sum(type_VX);
            edge_type(8,1)=sum(type_YY);
            edge_type(9,1)=sum(type_YX);
            edge_type(10,1)=sum(type_XX);

            IIprop=edge_type(1,1);
            ICprop=sum(edge_type(2:4,1));
            CCprop=sum(edge_type(5:10,1));
            %%
            assortative_edge_por = sum(edge_type([1,5,8,10], 1));
            disassortative_edge_por = sum(edge_type([2,3,4,6,7,9], 1));
            labels = {'assortative','disassortative'};
            datals = table('Size',[1,2],...
                'VariableTypes',{'string','string'},...
                'VariableNames',labels);
            assedge_type_proportion = [assortative_edge_por,disassortative_edge_por]./numedges(app.G);
            for i=1:2
                name = labels{i};
                num = assedge_type_proportion(i);
                datals.(name) = [num2str(num*100,'%.2f'), '%'];
            end
            app.UITable2.Data = datals;
            clear datals
            %%
            app.UIAxes_9.FontName='Times New Roman';

            % [~,et_ind]=sort(edge_type,'descend');
            % [edge_type_sort,et_ind]=sort(edge_type,'descend');
            % explode = zeros(1,10);
            % explode(et_ind(1:3,1))=1;
            % pie(edge_type,explode,'Parent',app.UIAxes_9);
            pie(edge_type(edge_type>0),'Parent',app.UIAxes_9);
            colormap(app.UIAxes_9,"colorcube")
            labels = {'II','IV','IY','IX','VV','VY','VX','YY','YX','XX'};

            app.UIAxes_9.Title.String = 'Edge distribution';
            app.UIAxes_9.Title.Position = [0.5 1.5];
            % Create legend
            labels_final = labels(edge_type>0);
            legend(app.UIAxes_9,labels_final,'Location','southeastoutside','FontAngle','italic',...
                'FontName','Times New Roman','FontSize',8);

            app.edge_type_proportion = edge_type./numedges(app.G);
            app.edge_type_proportion = app.edge_type_proportion';
            datals = table('Size',[1,10],...
                'VariableTypes',{'string','string','string','string','string','string','string','string','string','string'},...
                'VariableNames',labels);
            for i=1:10
                name = labels{i};
                num = app.edge_type_proportion(i);
                datals.(name) = [num2str(num*100,'%.2f'), '%'];
            end
            app.UITable_edgetype.Data = datals;
            %%
            % cla(app.UIAxes_4)
            % LWidths = 2*app.G.Edges.Length/max(app.G.Edges.Length);
            % colcomb=[128, 0, 128;0, 128, 128;128, 128, 0]./256;
            % deg=degree(app.G);
            % for i=1:numedges(app.G)
            %     n1=app.G.Edges.EndNodes(i,1);
            %     n2=app.G.Edges.EndNodes(i,2);
            %     x1=app.node_pos(n1,1);
            %     x2=app.node_pos(n2,1);
            %     y1=app.node_pos(n1,2);
            %     y2=app.node_pos(n2,2);
            %     x=[x1,x2];
            %     y=[y1,y2];
            %     d1=deg(n1);
            %     d2=deg(n2);
            %     if d1==1 && d2==1
            %         typec=1;
            %     else
            %         if d1==1 || d2==1
            %             typec=2;
            %         else
            %             typec=3;
            %         end
            %     end
            %     line(x,y,'LineStyle','-','Color',colcomb(typec,:),'LineWidth',LWidths(i,1),'Parent',app.UIAxes_4)
            % 
            % end
            % [image_row,image_col] = size(app.BWNODE);
            % app.UIAxes_4.XLim=[0,image_col];
            % app.UIAxes_4.YLim=[0,image_row];
            % app.UIAxes_4.YDir="reverse";
            % % app.UIAxes.Box="on";
            % % app.UIAxes.XAxis.Visible="on";
            % % app.UIAxes.YAxis.Visible="on";
            % % app.UIAxes.Title.String = 'Graph';
            % daspect(app.UIAxes_4,[1 1 1])
            % axis(app.UIAxes,'tight')


            %%
            % cla(app.UIAxes_10)
            % x="edge";
            % y=[IIprop,ICprop,CCprop]./numedges(app.G);
            % disp(y)
            % b=bar(x,y,'stacked','Parent',app.UIAxes_10);
            % b(1).FaceColor = colcomb(1,:);
            % b(2).FaceColor = colcomb(2,:);
            % b(3).FaceColor = colcomb(3,:);
            % axis(app.UIAxes_10,'tight')
            % app.UIAxes_10.YLim=[0,1];
            % % Create legend
            % lab={'II','IC','CC'};
            % legend(app.UIAxes_10,lab,'Location','southoutside','FontAngle','italic',...
            %     'FontName','Helvetica','FontSize',8);
            % app.UIAxes_10.YTickLabelMode = 'auto'; 
            % app.UIAxes_10.YMinorTick = "on";
            % % grid(app.UIAxes_10,"on")
            % app.UIAxes_10.XColor = 'k';
            % app.UIAxes_10.YColor = 'k';

            %%
            cla(app.UIAxes_4)
            % 初始化三元相图(Init ternary plot)
            app.UIAxes_4.FontName='Times New Roman';
            STA=STernary(app.UIAxes_4);
            % 背景色变为灰色(Set the background color to gray)
            set(STA,'Color',[.9,.9,.9]);
            % 设置标签文本(Set Label string)
            set(STA,'A_Label','String','I-I')
            set(STA,'B_Label','String','I-C')
            set(STA,'C_Label','String','C-C')
            STA.set('A_TickLabelStr',{'0.2','0.4','0.6','0.8',''})
            STA.set('B_TickLabelStr',{'0.2','0.4','0.6','0.8',''})
            STA.set('C_TickLabelStr',{'0.2','0.4','0.6','0.8',''})
            % STA.set('A_TickLabelStr',{'20%','40%','60%','80%','100%'})
            % STA.set('B_TickLabelStr',{'20%','40%','60%','80%','100%'})
            % STA.set('C_TickLabelStr',{'20%','40%','60%','80%','100%'})

            % 绘制散点图(Draw scatter plot)
            app.M=numedges(app.G);
            ii=IIprop/app.M; 
            disp(ii)
            ic=ICprop/app.M; 
            disp(ic)
            cc=CCprop/app.M; 
            disp(cc)

            colcomb=[128, 0, 128;0, 128, 128;128, 128, 0]./256;
            % 绘制折线图及散点(Draw lines and scatter)
            STA.SPlot([ii,ii]*10,  [ic,0]*10,   [cc,1-ii]*10,'LineWidth',2,'Color',colcomb(1,:));
            STA.SPlot([ii,1-ic]*10,[ic,ic]*10,  [cc,0]*10,'LineWidth',2,'Color',colcomb(2,:));
            STA.SPlot([ii,0]*10,   [ic,1-cc]*10,[cc,cc]*10,'LineWidth',2,'Color',colcomb(3,:));
            STA.SScatter(ii*10,ic*10,cc*10,80,'filled','CData',[1,1,1],'MarkerEdgeColor',[0,0,0],'LineWidth',2);

            % 绘制文本(Draw text)
            % STA.SText(ii+.1,ic,cc,'(20%,30%,50%)','FontSize',16)

        end

        % Button pushed function: ChoosetocalculateotherpropsofGButton
        function ChoosetocalculateotherpropsofGButtonPushed(app, event)
            app.M = numedges(app.G);
            N = numnodes(app.G);
            
            n_step=(app.CheckBox.Value && isempty(app.DiameterDEditField.Value)) +...
                (app.CheckBox_2.Value && isempty(app.ClusteringCEditField.Value)) +...
                (app.CheckBox_3.Value && isempty(app.EfficiencyEEditField.Value))+...
                (app.CheckBox_4.Value && isempty(app.SmallworldEditField.Value))+...
                (app.CheckBox_5.Value && isempty(app.SmallworldEditField_2.Value))+...
                (app.CheckBox_6.Value && isempty(app.ModularityQEditField.Value))+...
                (app.CheckBox_7.Value && isempty(app.PearsonrEditField.Value))+...
                (app.CheckBox_8.Value && isempty(app.SquareClusteringCsEditField.Value));
            ns=0;
            progress_bar=uiprogressdlg(app.TopoFracUIFigure,'Title','Properties analysis',...
                'Message','Calculating Props','Cancelable','on');
            
            %%
            % if app.CheckBox.Value ==1 && isempty(app.DiameterDEditField.Value)
            %     progress_bar.Message = 'Calculating the edge density of G';
            % 
            %     app.DiameterDEditField.Value = 2*app.M/(N*(N-1));
            % 
            %     progress_bar.Value = (ns+1)/n_step;
            % end

            %%
            if app.CheckBox_2.Value ==1 && isempty(app.ClusteringCEditField.Value)
                progress_bar.Message = 'Calculating the clustering coefficient C of G';

                app.Cluster=zeros(numnodes(app.G),1);
                for ni=1:numnodes(app.G)
                    app.Cluster(ni)=node_clustering_coefficient(ni,app.G,app.A);
                end
                app.Cluster_mean=mean(app.Cluster);
                app.G.Nodes.Cluster=app.Cluster;
                app.ClusteringCEditField.Value = app.Cluster_mean;
                
                % 求最大连通分量的平均群聚系数
                app.Clusterlargc=zeros(numnodes(app.Glargc),1);
                for ni=1:numnodes(app.Glargc)
                    app.Clusterlargc(ni)=node_clustering_coefficient(ni,app.Glargc,app.Alargc);
                end
                app.Clusterlargc_mean=mean(app.Clusterlargc);
                app.ClusteringCEditField_2.Value = app.Clusterlargc_mean;

                progress_bar.Value = (ns+1)/n_step;
            end
            %%
            if app.CheckBox_3.Value ==1 && isempty(app.EfficiencyEEditField.Value)
                progress_bar.Message = 'Calculating the efficiency coefficient of G';

                [app.Efficiency_mean,~]=graph_efficiency(app.G);
                app.EfficiencyEEditField.Value = app.Efficiency_mean;

                [app.Efficiencylargc_mean,~]=graph_efficiency(app.Glargc);
                app.EfficiencyEEditField_2.Value = app.Efficiencylargc_mean;

                progress_bar.Value = (ns+1)/n_step;
            end

            %             writematrix(numnodes(app.G),wfilename,'Sheet',app.prefixname,'Range','E1')
            %             writematrix(app.Efficiency_mean,wfilename,'Sheet',app.prefixname,'Range','F1')
            %             writematrix(app.Cluster_mean,wfilename,'Sheet',app.prefixname,'Range','G1')
            %             save(app.matname)
            %%
            if app.CheckBox_7.Value == 1 && isempty(app.PearsonrEditField.Value)
                progress_bar.Message = 'Calculating the degree pearson correlation coefficient of G';

                app.Pearson_coe = pearson_assortative(app.G);
                app.PearsonrEditField.Value = app.Pearson_coe;

                app.Pearson_coe_Glargc = pearson_assortative(app.Glargc);
                app.PearsonrEditField_2.Value = app.Pearson_coe_Glargc;

                progress_bar.Value = (ns+1)/n_step;
            end
            %%
            % app.tab_cc=sortrows(app.tab_cc,2,'descend');
            % writematrix(app.tab_cc(:,2),wfilename,'Sheet',app.prefixname,'Range','E2')
            % for i=1:size(app.tab_cc,1)
            %     clear subG subA subG_Cluster subG_Cluster_mean subG_Efficiency_mean
            %     cci=app.tab_cc(i,1);
            %     ci=find(cc==cci);
            %     if length(ci)<2
            %         continue
            %     else
            %         subG=subgraph(app.G,ci);
            %         if i<=3
            %             figure,plot(subG);
            %             figure,plot(subG,'Layout','force','UseGravity',true)
            %         end
            %         subA=full(adjacency(subG));
            %         subG_Cluster=zeros(numnodes(subG),1);
            %         for ni=1:numnodes(subG)
            %             subG_Cluster(ni)=node_clustering_coefficient(ni,subG,subA);
            %         end
            %         subG_Cluster_mean=mean(subG_Cluster);
            %         [subG_Efficiency_mean,~]=graph_efficiency(subG);
            %
            %         writematrix(subG_Efficiency_mean,wfilename,'Sheet',app.prefixname,'Range',['F',num2str(i+1)])
            %         writematrix(subG_Cluster_mean,wfilename,'Sheet',app.prefixname,'Range',['G',num2str(i+1)])
            %     end
            % end
            % save(app.matname)
            %%
            % for i=1:size(tab_cc,1)
            %     clear subG
            %     cci=tab_cc(i,1);
            %     ci=find(cc==cci);
            %     subG=subgraph(G,ci);
            %     if i<=3
            %         figure,plot(subG);
            %         figure,plot(subG,'Layout','force','UseGravity',true)
            %     end
            % end


             cbn = app.CheckBox.Value+...
                app.CheckBox_4.Value+...
                app.CheckBox_5.Value+...
                app.CheckBox_6.Value+...
                app.CheckBox_8.Value;
            if cbn>0                
                % 检查有没有txt文件，有的话删除
                nwx_filename = [app.prefixname, '-networkx.txt'];
                if exist(nwx_filename, 'file') == 2
                    % 如果文件存在，删除它
                    delete(nwx_filename);
                    fprintf('文件 %s 已被删除。\n', nwx_filename);
                else
                    fprintf('文件 %s 不存在于当前文件夹中。\n', nwx_filename);
                end
                % 写txt文件
                Adjacency_info_to_networkx(app.A,nwx_filename)
                
                % 检查有没有py文件，有的话删除
                lsn = [app.prefixname, '-networkx.py'];
                if exist(lsn, 'file') == 2
                    % 如果文件存在，删除它
                    delete(lsn);
                    fprintf('文件 %s 已被删除。\n', lsn);
                else
                    fprintf('文件 %s 不存在于当前文件夹中。\n', lsn);
                end

                % 改扩展名
                file = dir([app.prefixname, '-networkx.txt']);
                len = length(file);
                for i = 1 : len
                    oldname0 = string(file(i).name);
                    oldname = strcat(',',oldname0);
                    Date=string(regexp(oldname,'.*(?=\.txt)','match'));
                    newname = strcat(Date,'.py');
                    eval(['!rename',char(oldname),char(newname)]);%要用char  string不行
                end
                
                % 运行py文件，得到图G
                app.pyG = pyrunfile([app.prefixname, '-networkx.py'], "G");
                py.importlib.import_module('Topofracpyfun');
                display(app.pyG)
                %%
                
                lsn = [app.prefixname, '-largc-networkx.'];
                if exist(lsn, 'file') == 2
                    % 如果文件存在，删除它
                    delete(lsn);
                    fprintf('文件 %s 已被删除。\n', lsn);
                else
                    fprintf('文件 %s 不存在于当前文件夹中。\n', lsn);
                end
                lsn = [app.prefixname, '-largc-networkx.py'];
                if exist(lsn, 'file') == 2
                    % 如果文件存在，删除它
                    delete(lsn);
                    fprintf('文件 %s 已被删除。\n', lsn);
                else
                    fprintf('文件 %s 不存在于当前文件夹中。\n', lsn);
                end
                % 写txt文件
                Adjacency_info_to_networkx(app.Alargc, [app.prefixname, '-largc-networkx.txt'])

                % 改扩展名
                file2 = dir([app.prefixname, '-largc-networkx.txt']);
                for i = 1 : length(file2)
                    oldname0 = string(file2(i).name);
                    oldname = strcat(',',oldname0);
                    Date=string(regexp(oldname,'.*(?=\.txt)','match'));
                    newname = strcat(Date,'.py');
                    eval(['!rename',char(oldname),char(newname)]);%要用char  string不行
                end

                % 运行py文件，得到最大连通分量的图Glargc
                app.pyGlargc = pyrunfile([app.prefixname, '-largc-networkx.py'], "G");
                py.importlib.import_module('Topofracpyfun');
                display(app.pyGlargc)
            end
            %%
            if app.CheckBox.Value ==1 && isempty(app.DiameterDEditField.Value)
                progress_bar.Message = 'Calculating the diameter of G';

                app.Diameter = py.Topofracpyfun.pydiameter(app.pyG);
                app.DiameterDEditField.Value = double(app.Diameter);

                progress_bar.Value = (ns+1)/n_step;
            end
            if app.CheckBox_4.Value == 1  && isempty(app.SmallworldEditField.Value)
                progress_bar.Message = 'Calculating the small world coefficient σ of G';
                
                app.SW_sigma = py.Topofracpyfun.pysigma(app.pyG);
                app.SmallworldEditField.Value = app.SW_sigma;

                progress_bar.Value = (ns+1)/n_step;
            end
            if app.CheckBox_5.Value == 1 && isempty(app.SmallworldEditField_2.Value)
                progress_bar.Message = 'Calculating the small world coefficient ω of G';

                app.SW_omega = py.Topofracpyfun.pyomega(app.pyG);
                app.SmallworldEditField_2.Value = app.SW_omega;

                progress_bar.Value = (ns+1)/n_step;
            end
            if app.CheckBox_6.Value == 1 && isempty(app.ModularityQEditField.Value)
                progress_bar.Message = 'Calculating the modularity Q of G';

                app.Q_modularity = py.Topofracpyfun.pyQ(app.pyG);
                app.ModularityQEditField.Value = app.Q_modularity;

                progress_bar.Value = (ns+1)/n_step;
            end
            if app.CheckBox_8.Value == 1   && isempty(app.SquareClusteringCsEditField.Value)
                progress_bar.Message = 'Calculating the square clustering coefficient Cs of G';

                app.C_square = py.Topofracpyfun.pyC_suqare(app.pyG);
                % app.G.Nodes.C_square = app.C_square;
                app.SquareClusteringCsEditField.Value = app.C_square;

                app.C_square_largc = py.Topofracpyfun.pyC_suqare(app.pyGlargc);
                app.SquareClusteringCsEditField_2.Value = app.C_square_largc;

                progress_bar.Value = (ns+1)/n_step;
            end
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            display(selectedNodes)
            %             G_need_cal =
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            display(checkedNodes)
        end

        % Button pushed function: CalculatethehydraulicconnectivityfButton
        function CalculatethehydraulicconnectivityfButtonPushed(app, event)
            ni = app.node_type_proportion(1);
%             nv = app.node_type_proportion(2);
            ny = app.node_type_proportion(3);
            nx = app.node_type_proportion(4);
            etac = 2.94*(4*nx+2*ny)/(4*nx+2*ny+ni)-2.13;
            app.f_hydraulic_connectivity = max(etac,0);
            app.EditField_6.Value = num2str(app.f_hydraulic_connectivity,3);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            [image_row,image_col] = size(app.BWNODE);
            app.map_L =  app.EntertherealwidthofthetraceimageunitmEditField.Value;
            if app.map_L<=0
                error('The real width of the trace image should be positive.')
            end
            
            app.map_H = image_row*app.map_L/image_col; disp(app.map_H)
            app.area = app.map_H*app.map_L;
            app.kf_eff = zeros(2);
            for i = 1:numedges(app.G)
                Li = app.G.Edges.Length(i)*app.map_L/image_col;
                theta_i = app.G.Edges.Slope(i);
                ni_nj = [cosd(theta_i)*cosd(theta_i),cosd(theta_i)*sind(theta_i);
                    cosd(theta_i)*sind(theta_i),sind(theta_i)*sind(theta_i)];
                if app.DistributionformoffractureapertureDropDown.Value(1)=='常'
                    bf = app.b0mEditField.Value;
                    app.kf_eff = app.kf_eff + (1e3*10/1e-3)*(bf^3/12)*Li*(ones(2)-ni_nj)/app.area;
                else
                    bf = app.b0mEditField.Value*sqrt(Li);
                    app.kf_eff = app.kf_eff + (1e3*10/1e-3)*(bf^3/12)*Li*(ones(2)-ni_nj)/app.area;
                end
            end
            disp(app.kf_eff)
            app.kf_eff = app.kf_eff./numedges(app.G);
            app.kf_eff = app.kf_eff.*app.f_hydraulic_connectivity;
            
            app.EditField_7.Value = num2str(app.kf_eff(1,1),3);
            app.EditField_9.Value = num2str(app.kf_eff(1,2),3);
            app.EditField_10.Value = num2str(app.kf_eff(2,1),3);
            app.EditField_11.Value = num2str(app.kf_eff(2,2),3);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.prefixname = app.FilenameofoutputmatandxlsxEditField.Value;            
            app.matname = [app.prefixname,'.mat'];

            TPFC.G=app.G;
            TPFC.Glargc=app.Glargc;
            TPFC.n_concom = app.n_concom;
            TPFC.tab_cc = app.tab_cc;
            TPFC.BWNODE = app.BWNODE;
            TPFC.BWicp = app.BWicp;
            TPFC.node_type_proportion = app.node_type_proportion;
            TPFC.edge_type_proportion = app.edge_type_proportion;
            
            if app.CheckBox_4.Value==1
                TPFC.SW_sigma = app.SW_sigma;
            end
            if app.CheckBox_5.Value==1
                TPFC.SW_omega = app.SW_omega;
            end
            if app.CheckBox_6.Value==1
                TPFC.Q_modularity = app.Q_modularity;
            end
            if ~isempty(app.EditField_6.Value)
                TPFC.f_hydraulic_connectivity = app.f_hydraulic_connectivity;
            end
            if app.CheckBox_7.Value==1
                TPFC.Pearson_coe = app.Pearson_coe;
            end
            if ~isempty(app.EditField_7.Value)
                TPFC.kf_eff = app.kf_eff;
            end

            save([app.prefixname, '.mat'], '-struct',"TPFC");

            %%
            wfilename=[app.prefixname, '.xlsx'];
            % sheet-NODES
            writematrix('No.i',wfilename,'Sheet','NODES','Range','A1')
            writematrix((1:numnodes(app.G)).',wfilename,'Sheet','NODES','Range','A3')

            writematrix('Degree',wfilename,'Sheet','NODES','Range','B1')
            writematrix(mean(degree(app.G)),wfilename,'Sheet','NODES','Range','B2')
            writematrix(degree(app.G),wfilename,'Sheet','NODES','Range','B3')

            
            writematrix('Cluster',wfilename,'Sheet','NODES','Range','C1')
            writematrix(app.Cluster_mean,wfilename,'Sheet','NODES','Range','C2')
            writematrix(app.Cluster,wfilename,'Sheet','NODES','Range','C3')

            writematrix('Efficiency',wfilename,'Sheet','NODES','Range','D1')
            writematrix(app.Efficiency_mean,wfilename,'Sheet','NODES','Range','D2')
            writematrix(app.Efficiency,wfilename,'Sheet','NODES','Range','D3')

            writematrix('Betweenness Centrality',wfilename,'Sheet','NODES','Range','E1')
            writematrix(app.norm_wbc_mean,wfilename,'Sheet','NODES','Range','E2')
            writematrix(app.norm_wbc,wfilename,'Sheet','NODES','Range','E3')

            writematrix('Closeness Centrality',wfilename,'Sheet','NODES','Range','F1')
            writematrix(app.closecen_mean,wfilename,'Sheet','NODES','Range','F2')
            writematrix(app.closecen,wfilename,'Sheet','NODES','Range','F3')
            
            % sheet-EDGES
            writematrix('n1',wfilename,'Sheet','EDGES','Range','A1')
            writematrix('n2',wfilename,'Sheet','EDGES','Range','B1')
            writematrix(app.G.Edges.EndNodes,wfilename,'Sheet','EDGES','Range','A2')
            writematrix('Length in bitmap',wfilename,'Sheet','EDGES','Range','C1')
            writematrix(app.G.Edges.Length,wfilename,'Sheet','EDGES','Range','C2')
            writematrix('Slope',wfilename,'Sheet','EDGES','Range','D1')
            writematrix(app.G.Edges.Slope,wfilename,'Sheet','EDGES','Range','D2')
        end

        % Value changed function: 
        % EntertherealwidthofthetraceimageunitmEditField
        function EntertherealwidthofthetraceimageunitmEditFieldValueChanged(app, event)
            value = app.EntertherealwidthofthetraceimageunitmEditField.Value;
            app.map_L = value;
            [image_row,image_col] = size(app.L_node);
            app.map_H = image_row*app.map_L/image_col;
            app.area = app.map_H*app.map_L;
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            value = app.LayoutDropDown.Value;
            ax = app.UIAxes_5; % 获取当前坐标轴
            children = get(ax, 'Children'); % 获取当前坐标轴的子对象

            if isempty(children)
                disp('The axes are empty.');
            else
                disp('The axes contain plotted data.');
                if strcmp(value,'Force-directed')
                    layout(app.hcen,"force","UseGravity",true)
                end
                if strcmp(value,'Original position')
                    app.hcen.XData=app.node_pos(:,1);
                    app.hcen.YData=app.node_pos(:,2);
                end
                if strcmp(value,'Circle')
                    layout(app.hcen,"circle")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TopoFracUIFigure = uifigure('Visible', 'off');
            app.TopoFracUIFigure.Name = 'TopoFrac';
            app.TopoFracUIFigure.Icon = fullfile(pathToMLAPP, 'icon', 'icon.png');
            app.InputPanel = uipanel(app.TopoFracUIFigure);
            app.InputPanel.Title = 'Input';
            app.Label_3 = uilabel(app.InputPanel);
            app.Label_3.Text = 'Filename of fracture trace image';
            app.FilenameoffracturetraceimageEditField = uieditfield(app.InputPanel, 'text');
            app.FilenameoffracturetraceimageEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameoffracturetraceimageEditFieldValueChanged, true);
            app.SelectButton = uibutton(app.InputPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.CharacterizationPanel = uipanel(app.TopoFracUIFigure);
            app.CharacterizationPanel.Title = 'Characterization';
            app.ConstructGraphGButton = uibutton(app.CharacterizationPanel, 'push');
            app.ConstructGraphGButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructGraphGButtonPushed, true);
            app.ConstructGraphGButton.Text = 'Construct Graph G';
            app.MNumberofedgesEditFieldLabel = uilabel(app.CharacterizationPanel);
            app.MNumberofedgesEditFieldLabel.Text = 'M (Number of edges) :';
            app.MNumberofedgesEditField = uieditfield(app.CharacterizationPanel, 'numeric');
            app.NccNumberofconnectedcomponentsEditFieldLabel = uilabel(app.CharacterizationPanel);
            app.NccNumberofconnectedcomponentsEditFieldLabel.Text = 'Ncc (Number of connected components) :';
            app.NccNumberofconnectedcomponentsEditField = uieditfield(app.CharacterizationPanel, 'numeric');
            app.NNumberofnodesEditFieldLabel = uilabel(app.CharacterizationPanel);
            app.NNumberofnodesEditFieldLabel.Text = 'N (Number of nodes) :';
            app.NNumberofnodesEditField = uieditfield(app.CharacterizationPanel, 'numeric');
            app.UITable = uitable(app.CharacterizationPanel);
            app.UITable.ColumnName = {'Serial Number'; 'The number of  nodes in the Connected Component'; 'Node%'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true];
            app.Image4 = uiimage(app.CharacterizationPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icon', 'icon2.png');
            app.PropertiesAnalysisPanel = uipanel(app.TopoFracUIFigure);
            app.PropertiesAnalysisPanel.Title = 'Properties Analysis';
            app.SquareClusteringCsEditField_2 = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.SquareClusteringCsEditField_2.AllowEmpty = 'on';
            app.SquareClusteringCsEditField_2.Value = [];
            app.UITable_nodetype = uitable(app.PropertiesAnalysisPanel);
            app.UITable_nodetype.ColumnName = {'I-shaped (k = 1)'; 'V-shaped (k = 2)'; 'Y-shaped (k = 3)'; 'X-shaped (k ≥ 4)'};
            app.UITable_nodetype.RowName = {};
            app.UITable_edgetype = uitable(app.PropertiesAnalysisPanel);
            app.UITable_edgetype.ColumnName = {'II'; 'IV'; 'IY'; 'IX'; 'VV'; 'VY'; 'VX'; 'YY'; 'YX'; 'XX'};
            app.UITable_edgetype.RowName = {};
            app.CalculatethedegreedistributionofGButton = uibutton(app.PropertiesAnalysisPanel, 'push');
            app.CalculatethedegreedistributionofGButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethedegreedistributionofGButtonPushed, true);
            app.CalculatethedegreedistributionofGButton.Text = {'Calculate the degree '; 'distribution of G'};
            app.CalculatetheedgetypeinGButton = uibutton(app.PropertiesAnalysisPanel, 'push');
            app.CalculatetheedgetypeinGButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetheedgetypeinGButtonPushed, true);
            app.CalculatetheedgetypeinGButton.Text = {'Calculate the '; 'edge type in G'};
            app.ChoosetocalculateotherpropsofGButton = uibutton(app.PropertiesAnalysisPanel, 'push');
            app.ChoosetocalculateotherpropsofGButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetocalculateotherpropsofGButtonPushed, true);
            app.ChoosetocalculateotherpropsofGButton.Text = {'Choose to calculate '; 'other props of G'};
            app.CheckBox = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox.Text = '';
            app.MNN1Label = uilabel(app.PropertiesAnalysisPanel);
            app.MNN1Label.Text = 'Diameter D';
            app.DiameterDEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.DiameterDEditField.Limits = [0 Inf];
            app.DiameterDEditField.AllowEmpty = 'on';
            app.DiameterDEditField.Value = [];
            app.CheckBox_2 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_2.Text = '';
            app.ClusteringCEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.ClusteringCEditFieldLabel.Text = 'Clustering C ';
            app.ClusteringCEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.ClusteringCEditField.AllowEmpty = 'on';
            app.ClusteringCEditField.Value = [];
            app.ClusteringCEditField_2 = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.ClusteringCEditField_2.AllowEmpty = 'on';
            app.ClusteringCEditField_2.Value = [];
            app.CheckBox_3 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_3.Text = '';
            app.EfficiencyEEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.EfficiencyEEditFieldLabel.Text = 'Efficiency E';
            app.EfficiencyEEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.EfficiencyEEditField.AllowEmpty = 'on';
            app.EfficiencyEEditField.Value = [];
            app.CheckBox_4 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_4.Text = '';
            app.SmallworldEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.SmallworldEditFieldLabel.Text = 'Small-world σ';
            app.SmallworldEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.SmallworldEditField.AllowEmpty = 'on';
            app.SmallworldEditField.Value = [];
            app.CheckBox_5 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_5.Text = '';
            app.SmallworldEditField_2Label = uilabel(app.PropertiesAnalysisPanel);
            app.SmallworldEditField_2Label.Text = 'Small-world ω';
            app.SmallworldEditField_2 = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.SmallworldEditField_2.AllowEmpty = 'on';
            app.SmallworldEditField_2.Value = [];
            app.CheckBox_6 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_6.Text = '';
            app.ModularityQEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.ModularityQEditFieldLabel.Text = 'Modularity Q';
            app.ModularityQEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.ModularityQEditField.AllowEmpty = 'on';
            app.ModularityQEditField.Value = [];
            app.Label_8 = uilabel(app.PropertiesAnalysisPanel);
            app.Label_8.Text = 'Tip: 1. Check the properties wanted. Then press the left button to calculate; 2. Small world coefficient is only applicable to connected graph. If the graph G of a natural fracture network consists of multiple connected components, its largest connected component Gc is used to calculate its small world coefficient.';
            app.CheckBox_7 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_7.Text = '';
            app.PearsonrEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.PearsonrEditFieldLabel.Text = 'Pearson r';
            app.PearsonrEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.PearsonrEditField.AllowEmpty = 'on';
            app.PearsonrEditField.Value = [];
            app.UITable2 = uitable(app.PropertiesAnalysisPanel);
            app.UITable2.ColumnName = {'Assortative edge%'; 'Disassortative%'};
            app.UITable2.RowName = {};
            app.CheckBox_8 = uicheckbox(app.PropertiesAnalysisPanel);
            app.CheckBox_8.Text = '';
            app.SquareClusteringCsEditFieldLabel = uilabel(app.PropertiesAnalysisPanel);
            app.SquareClusteringCsEditFieldLabel.Text = 'Square Clustering Cs';
            app.SquareClusteringCsEditField = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.SquareClusteringCsEditField.AllowEmpty = 'on';
            app.SquareClusteringCsEditField.Value = [];
            app.EfficiencyEEditField_2 = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.EfficiencyEEditField_2.AllowEmpty = 'on';
            app.EfficiencyEEditField_2.Value = [];
            app.PearsonrEditField_2 = uieditfield(app.PropertiesAnalysisPanel, 'numeric');
            app.PearsonrEditField_2.AllowEmpty = 'on';
            app.PearsonrEditField_2.Value = [];
            app.VisualizationPanel = uipanel(app.TopoFracUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.VisualizationPanel);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.VisualizationPanel);
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.VisualizationPanel);
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_5 = uiaxes(app.VisualizationPanel);
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTickLabel = '';
            app.UIAxes_6 = uiaxes(app.VisualizationPanel);
            app.UIAxes_6.XTickLabel = '';
            app.UIAxes_6.YTickLabel = '';
            app.UIAxes_8 = uiaxes(app.VisualizationPanel);
            app.UIAxes_8.XTickLabel = '';
            app.UIAxes_8.YTickLabel = '';
            app.UIAxes_9 = uiaxes(app.VisualizationPanel);
            app.UIAxes_9.XTickLabel = '';
            app.UIAxes_9.YTickLabel = '';
            app.UIAxes_10 = uiaxes(app.VisualizationPanel);
            app.UIAxes_10.XTickLabel = '';
            app.UIAxes_10.YTickLabel = '';
            app.UIAxes_7 = uiaxes(app.VisualizationPanel);
            app.UIAxes_7.XTickLabel = '';
            app.UIAxes_7.YTickLabel = '';
            app.LayoutDropDownLabel = uilabel(app.VisualizationPanel);
            app.LayoutDropDownLabel.Visible = 'off';
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.VisualizationPanel);
            app.LayoutDropDown.Items = {'Original position', 'Force-directed', 'Circle'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Visible = 'off';
            app.LayoutDropDown.Value = 'Original position';
            app.EquivalentpermeabilityPanel = uipanel(app.TopoFracUIFigure);
            app.EquivalentpermeabilityPanel.Title = 'Equivalent permeability';
            app.Image = uiimage(app.EquivalentpermeabilityPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon', 'the hydraulic connectivity.png');
            app.CalculatethehydraulicconnectivityfButton = uibutton(app.EquivalentpermeabilityPanel, 'push');
            app.CalculatethehydraulicconnectivityfButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethehydraulicconnectivityfButtonPushed, true);
            app.CalculatethehydraulicconnectivityfButton.Text = 'Calculate the hydraulic connectivity f';
            app.Label_9 = uilabel(app.EquivalentpermeabilityPanel);
            app.Label_9.Text = '=';
            app.EditField_6 = uieditfield(app.EquivalentpermeabilityPanel, 'text');
            app.Image2 = uiimage(app.EquivalentpermeabilityPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icon', 'Keff-f.png');
            app.Button_8 = uibutton(app.EquivalentpermeabilityPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'Calculate the equivalent permeability';
            app.EditField_7Label = uilabel(app.EquivalentpermeabilityPanel);
            app.EditField_7Label.Text = '=';
            app.EditField_7 = uieditfield(app.EquivalentpermeabilityPanel, 'text');
            app.EditField_9 = uieditfield(app.EquivalentpermeabilityPanel, 'text');
            app.EditField_10 = uieditfield(app.EquivalentpermeabilityPanel, 'text');
            app.EditField_11 = uieditfield(app.EquivalentpermeabilityPanel, 'text');
            app.Label_11 = uilabel(app.EquivalentpermeabilityPanel);
            app.Label_11.Text = 'Distribution form of fracture aperture';
            app.DistributionformoffractureapertureDropDown = uidropdown(app.EquivalentpermeabilityPanel);
            app.DistributionformoffractureapertureDropDown.Items = {'Constant: bi = b0', 'bi = b0*sqrt(Li). Li is the length of edge ei.'};
            app.DistributionformoffractureapertureDropDown.Value = 'Constant: bi = b0';
            app.b0mEditField = uieditfield(app.EquivalentpermeabilityPanel, 'numeric');
            app.b0mEditField.Value = 0.001;
            app.Image3 = uiimage(app.EquivalentpermeabilityPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icon', 'b0.png');
            app.msLabel = uilabel(app.EquivalentpermeabilityPanel);
            app.msLabel.Text = '(m/s)';
            app.Label_12 = uilabel(app.EquivalentpermeabilityPanel);
            app.Label_12.Text = 'Enter the real width of the trace image (unit: m):';
            app.EntertherealwidthofthetraceimageunitmEditField = uieditfield(app.EquivalentpermeabilityPanel, 'numeric');
            app.EntertherealwidthofthetraceimageunitmEditField.Limits = [0 Inf];
            app.EntertherealwidthofthetraceimageunitmEditField.ValueChangedFcn = createCallbackFcn(app, @EntertherealwidthofthetraceimageunitmEditFieldValueChanged, true);
            app.EntertherealwidthofthetraceimageunitmEditField.Value = 1;
            app.OutputPanel = uipanel(app.TopoFracUIFigure);
            app.OutputPanel.Title = 'Output';
            app.FilenameofoutputmatandxlsxEditFieldLabel = uilabel(app.OutputPanel);
            app.FilenameofoutputmatandxlsxEditFieldLabel.Text = 'Filename of output *.mat and *.xlsx';
            app.FilenameofoutputmatandxlsxEditField = uieditfield(app.OutputPanel, 'text');
            app.FilenameofoutputmatandxlsxEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameofoutputmatandxlsxEditFieldValueChanged, true);
            app.FilenameofoutputmatandxlsxEditField.Value = 'FtoG';
            app.saveButton = uibutton(app.OutputPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.TopoFracUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TopoFrac

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TopoFracUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TopoFracUIFigure)
        end
    end
end